classdef Oscillator_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SerialPanel               matlab.ui.container.Panel
        ConnectButton             matlab.ui.control.Button
        DisconnectResetButton     matlab.ui.control.Button
        ConnectedLampLabel        matlab.ui.control.Label
        ConnectedLamp             matlab.ui.control.Lamp
        ListBox                   matlab.ui.control.ListBox
        RefreshListButton         matlab.ui.control.Button
        FrequencyPanel            matlab.ui.container.Panel
        FrequencySpinnerLabel     matlab.ui.control.Label
        FrequencySpinner          matlab.ui.control.Spinner
        SetFrequencyButton        matlab.ui.control.Button
        RequestedFreqLabel        matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        MHzLabel                  matlab.ui.control.Label
        MHzLabel_2                matlab.ui.control.Label
        ActualFreqLabel           matlab.ui.control.Label
        LookbelowLabel            matlab.ui.control.Label
        MHzLabel_3                matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        ReadnextseriallineButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        %add a check if still connected function
        function updateSerialViewer(app)
            global s
            text = "";
            while(s.BytesAvailable)
                text = text + fscanf(s);
                pause(0.1);
            end
            app.TextArea.Value = text;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            instrreset;
            app.ListBox.Items = seriallist;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global s
            s = serial(app.ListBox.Value);
            fopen(s);
            app.TextArea.Value = string([s.baudrate," ", s.status]);
            if (s.status == "open")
                app.ConnectedLamp.Color = [0 1 0];
            end
            app.ConnectButton.Enable = 0;
            app.SetFrequencyButton.Enable = 1;
            app.FrequencySpinner.Enable = 1;
            app.ListBox.Enable = 0;
            app.RefreshListButton.Enable = 0;
        end

        % Button pushed function: DisconnectResetButton
        function DisconnectResetButtonPushed(app, event)
            global s
            try
                fclose(s);
                if (s.status == "closed")
                    app.ConnectedLamp.Color = [1 0 0];
                end
                delete(s);
                clear s;
                app.ConnectButton.Enable = 1;
                app.SetFrequencyButton.Enable = 0;
                app.FrequencySpinner.Enable = 0;
                app.ListBox.Enable = 1;
                app.RefreshListButton.Enable = 1;
            catch
            end
            app.ListBox.Items = seriallist;
        end

        % Button pushed function: SetFrequencyButton
        function SetFrequencyButtonPushed(app, event)
            app.SetFrequencyButton.Enable = 0;
            global s
            try
                fwrite(s, app.FrequencySpinner.Value*1000000,'ulong');
                app.Label.Text = string(app.FrequencySpinner.Value);
                pause(0.1);
                updateSerialViewer(app);
                app.SetFrequencyButton.Enable = 1;
            catch
                DisconnectResetButtonPushed(app);
            end
        end

        % Button pushed function: ReadnextseriallineButton
        function ReadnextseriallineButtonPushed(app, event)
            updateSerialViewer(app);
        end

        % Button pushed function: RefreshListButton
        function RefreshListButtonPushed(app, event)
            app.ListBox.Items = seriallist;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SerialPanel = uipanel(app.UIFigure);
            app.SerialPanel.Title = 'Serial';
            app.ConnectButton = uibutton(app.SerialPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectResetButton = uibutton(app.SerialPanel, 'push');
            app.DisconnectResetButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectResetButtonPushed, true);
            app.DisconnectResetButton.Text = 'Disconnect/ Reset';
            app.ConnectedLampLabel = uilabel(app.SerialPanel);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.SerialPanel);
            app.ListBox = uilistbox(app.SerialPanel);
            app.RefreshListButton = uibutton(app.SerialPanel, 'push');
            app.RefreshListButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshListButtonPushed, true);
            app.RefreshListButton.Text = 'Refresh List';
            app.FrequencyPanel = uipanel(app.UIFigure);
            app.FrequencyPanel.Title = 'Frequency';
            app.FrequencySpinnerLabel = uilabel(app.FrequencyPanel);
            app.FrequencySpinnerLabel.Text = 'Frequency';
            app.FrequencySpinner = uispinner(app.FrequencyPanel);
            app.FrequencySpinner.Limits = [35 4200];
            app.FrequencySpinner.ValueDisplayFormat = '%11.8g';
            app.FrequencySpinner.Enable = 'off';
            app.FrequencySpinner.Value = 35;
            app.SetFrequencyButton = uibutton(app.FrequencyPanel, 'push');
            app.SetFrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @SetFrequencyButtonPushed, true);
            app.SetFrequencyButton.Enable = 'off';
            app.SetFrequencyButton.Text = 'Set Frequency';
            app.RequestedFreqLabel = uilabel(app.FrequencyPanel);
            app.RequestedFreqLabel.Text = 'Requested Freq';
            app.Label = uilabel(app.FrequencyPanel);
            app.Label.Text = '';
            app.MHzLabel = uilabel(app.FrequencyPanel);
            app.MHzLabel.Text = 'MHz';
            app.MHzLabel_2 = uilabel(app.FrequencyPanel);
            app.MHzLabel_2.Text = 'MHz';
            app.ActualFreqLabel = uilabel(app.FrequencyPanel);
            app.ActualFreqLabel.Text = 'Actual Freq';
            app.LookbelowLabel = uilabel(app.FrequencyPanel);
            app.LookbelowLabel.Text = 'Look below';
            app.MHzLabel_3 = uilabel(app.FrequencyPanel);
            app.MHzLabel_3.Text = 'MHz';
            app.TextArea = uitextarea(app.UIFigure);
            app.ReadnextseriallineButton = uibutton(app.UIFigure, 'push');
            app.ReadnextseriallineButton.ButtonPushedFcn = createCallbackFcn(app, @ReadnextseriallineButtonPushed, true);
            app.ReadnextseriallineButton.Text = 'Read next serial line';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Oscillator_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end